classdef gooApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        ApplyButton              matlab.ui.control.Button
        PL                       matlab.ui.control.Button
        PR                       matlab.ui.control.Button
        RegionEditField          matlab.ui.control.NumericEditField
        RegionEditFieldLabel     matlab.ui.control.Label
        LamdaEditField           matlab.ui.control.NumericEditField
        LamdaEditFieldLabel      matlab.ui.control.Label
        DyEditField              matlab.ui.control.NumericEditField
        DyEditFieldLabel         matlab.ui.control.Label
        DxEditField              matlab.ui.control.NumericEditField
        DxEditFieldLabel         matlab.ui.control.Label
        tFile                    matlab.ui.control.EditField
        SaveBox                  matlab.ui.control.CheckBox
        LoadButton               matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        AmpAx                    matlab.ui.control.UIAxes
        PhaseAx                  matlab.ui.control.UIAxes
        Tab2                     matlab.ui.container.Tab
        phasePrev                matlab.ui.control.Button
        phaseNext                matlab.ui.control.Button
        CalcButton               matlab.ui.control.Button
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        threshAx                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        COMPLEX_FEILD % Description
        IMAGES % all loaded images
        CURRENT_IMG % current image being displayed
        PHASES % generated Phase images
        CURRENT_PHASE % current phase being displayed
        TOTAL_IMG % Description
        PERAMS % Description
        SAVED % keeps tracks of when a peram is saved
    end
    
    methods (Access = private)
        
        function [results] = setVis(app, componet, state)
            if length(componet) > 1
                for i = 1:length(componet)
                    componet(i).Visible = state;
                end
            else
                componet.Visible = state;
                results = 0;
            end
            
        end
        
        function results = getVal(app, componet)
            
            results = componet.Value;
            
        end
      
        
        function results = peramsCheck(app, A,B)
            
            if size(A, 2) == size(B, 2) && A == B
                disp("passed")
                results = 1;
            else 
                disp("failed")
                results = 0;
            end

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            inny = app.PERAMS; %[app.DxEditField.Value app.DyEditField.Value app.LamdaEditField.Value app.RegionEditField.Value];
            
            if size(app.IMAGES) > 1
            for i = 1:length(app.IMAGES)
                %disp(string(app.IMAGES(i)))
                %take fName and feed into the phase rec w/ out split into 2
                %arrays and should just work?
                %fprintf("image: %s\n dx: %.2f dy: %.2f \nl: %.2f r: %.2f \n", string(app.IMAGES(i)), inny(i,1),inny(i,2),inny(i,3),inny(i,4))
                im = string(app.IMAGES(i));
                [complexFeild, phase, ~] = phaseRec(im, inny(i,1),inny(i,2),inny(i,3),inny(i,4),0);
                app.COMPLEX_FEILD = complexFeild;
                app.PHASES = phase;

            end
            else
                im = string(app.IMAGES);
                [complexFeild, phase, ~] = phaseRec(im, inny(1),inny(2),inny(3),inny(4),0);
                app.COMPLEX_FEILD = complexFeild;
                app.PHASES = phase;
            end
           
            disp(app.PHASES)
            %[complexFeild, phase, ampl] = phaseRec(app.getVal(app.tFile), inny(1),inny(2),inny(3),inny(4),0);
            %app.COMPLEX_FEILD = complexFeild;
            %app.PhaseImg.ImageSource = image(phase);
            %imagesc(phase, 'Parent', app.PhaseAx);
            %imagesc(ampl, 'Parent', app.AmpAx);
            %app.setVis(app.PhaseAx,"on");


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %disp("boop");
            [fName, fPath]= uigetfile("MultiSelect","on","*.bmp");
            app.TOTAL_IMG = length(fName);
            app.PERAMS = zeros(app.TOTAL_IMG, 4);
            app.SAVED = zeros(1, app.TOTAL_IMG);
            disp(app.PERAMS)
            disp(size(fName))
            if isempty(fName)
                fName = " ";
            
            elseif size(fName, 2) > 1
                app.IMAGES = fName;
                app.CURRENT_IMG = 1;
                img = imread(string(app.IMAGES(1)));
                imagesc( img, 'Parent', app.AmpAx);
                app.setVis([app.AmpAx, app.PL, app.PR], "on");
                app.tFile.Value = string(app.IMAGES(1));

            else 
                disp("else")
                app.IMAGES = string(fName);
                app.CURRENT_IMG = 1;
                img = imread(string(app.IMAGES));
                imagesc(img, 'Parent', app.AmpAx);
                app.setVis(app.AmpAx, "on");
                app.tFile.Value = string(fName);


            end
            
        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
        CF60 = FT(app.COMPLEX_FEILD);
        CF60_BW = imbinarize(log(abs(CF60).^2), app.ThresholdEditField.Value);
        imagesc(CF60_BW, 'Parent', app.threshAx);
    

        end

        % Button pushed function: PR
        function previewNext(app, event)
            cur = app.CURRENT_IMG + 1;
            boxes = [app.DxEditField.Value,app.DyEditField.Value,app.LamdaEditField.Value,app.RegionEditField.Value];
            saved = zeros(1,app.TOTAL_IMG);
            prev = cur - 1;
            disp("test")
            disp(saved)
            if cur > app.TOTAL_IMG
                cur = 1;
            else
                app.CURRENT_IMG = cur;
                disp(app.CURRENT_IMG)
                img = imread(string(app.IMAGES(app.CURRENT_IMG)));
                app.setVis(app.AmpAx,"off");
                imagesc(img, 'Parent', app.AmpAx);
                app.setVis(app.AmpAx,"on");
                app.tFile.Value = string(app.IMAGES(app.CURRENT_IMG));
            end
            
            checkSum = sum((app.PERAMS(prev,:) == boxes));

            disp (checkSum)

            if checkSum ~= 4 && saved(prev) == 0
                app.PERAMS(prev,:)= [app.DxEditField.Value app.DyEditField.Value app.LamdaEditField.Value app.RegionEditField.Value];
                app.SAVED(prev) = 1;
                disp(app.PERAMS)
                disp(saved)
            end
            if app.SAVED(app.CURRENT_IMG) == 1
                app.DxEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
                app.DyEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
                app.LamdaEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
                app.RegionEditField.Value = app.PERAMS(app.CURRENT_IMG, 4);
            else 
                app.DxEditField.Value = 0;
                app.DyEditField.Value = 0;
                app.LamdaEditField.Value = 0;
                app.RegionEditField.Value = 0;
            end

            
            % disp(app.PERAMS(app.CURRENT_IMG,:) == boxes);
            % if size(app.PERAMS) >= app.CURRENT_IMG
            %     app.DxEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
            %     app.DyEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
            %     app.LamdaEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
            %     app.RegionEditField.Value = app.PERAMS(app.CURRENT_IMG, 4);
            % 
            % else 
            %     app.DxEditField.Value = 0;
            %     app.DyEditField.Value = 0;
            %     app.LamdaEditField.Value = 0;
            %     app.RegionEditField.Value = 0;
            % end


        end

        % Button pushed function: PL
        function previewBack(app, event)
            cur = app.CURRENT_IMG - 1;
            boxes = [app.DxEditField.Value,app.DyEditField.Value,app.LamdaEditField.Value,app.RegionEditField.Value];
            saved = zeros(1,app.TOTAL_IMG);
            prev = cur + 1;
            disp("test")
            disp(saved)
            if cur < 1
                cur = 1;
            else
                app.CURRENT_IMG = cur;
                disp(app.CURRENT_IMG)
                img = imread(string(app.IMAGES(app.CURRENT_IMG)));
                app.setVis(app.AmpAx,"off");
                imagesc(img, 'Parent', app.AmpAx);
                app.setVis(app.AmpAx,"on");
                app.tFile.Value = string(app.IMAGES(app.CURRENT_IMG));
            end
            checkSum = sum((app.PERAMS(prev,:) == boxes));

            if checkSum ~= 4 && saved(prev) == 0
                app.PERAMS(prev,:)= [app.DxEditField.Value app.DyEditField.Value app.LamdaEditField.Value app.RegionEditField.Value];
                app.SAVED(prev) = 1;
                disp(app.PERAMS)
                disp(saved)
            end
            if app.SAVED(app.CURRENT_IMG) == 1
                app.DxEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
                app.DyEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
                app.LamdaEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
                app.RegionEditField.Value = app.PERAMS(app.CURRENT_IMG, 4);
            else 
                app.DxEditField.Value = 0;
                app.DyEditField.Value = 0;
                app.LamdaEditField.Value = 0;
                app.RegionEditField.Value = 0;
            end

            % if size(app.PERAMS) >= app.CURRENT_IMG
            %     app.DxEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
            %     app.DyEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
            %     app.LamdaEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
            %     app.RegionEditField.Value = app.PERAMS(app.CURRENT_IMG, 4);
            % 
            % else 
            %     app.DxEditField.Value = 0;
            %     app.DyEditField.Value = 0;
            %     app.LamdaEditField.Value = 0;
            %     app.RegionEditField.Value = 0;
            % end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %app.PERAMS= zeros(length(app.IMAGES),4); 
            app.PERAMS(app.CURRENT_IMG,:)= [app.DxEditField.Value app.DyEditField.Value app.LamdaEditField.Value app.RegionEditField.Value];
            disp(app.PERAMS)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.PhaseAx = uiaxes(app.Tab);
            title(app.PhaseAx, 'Phase')
            xlabel(app.PhaseAx, 'X')
            ylabel(app.PhaseAx, 'Y')
            zlabel(app.PhaseAx, 'Z')
            colormap(app.PhaseAx, 'gray')
            app.PhaseAx.Visible = 'off';
            app.AmpAx = uiaxes(app.Tab);
            title(app.AmpAx, 'Preview ')
            xlabel(app.AmpAx, 'X')
            ylabel(app.AmpAx, 'Y')
            zlabel(app.AmpAx, 'Z')
            colormap(app.AmpAx, 'gray')
            app.AmpAx.Visible = 'off';
            app.RunButton = uibutton(app.Tab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LoadButton = uibutton(app.Tab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveBox = uicheckbox(app.Tab);
            app.SaveBox.Text = 'Save';
            app.tFile = uieditfield(app.Tab, 'text');
            app.tFile.Editable = 'off';
            app.DxEditFieldLabel = uilabel(app.Tab);
            app.DxEditFieldLabel.Text = 'Dx';
            app.DxEditField = uieditfield(app.Tab, 'numeric');
            app.DyEditFieldLabel = uilabel(app.Tab);
            app.DyEditFieldLabel.Text = 'Dy';
            app.DyEditField = uieditfield(app.Tab, 'numeric');
            app.LamdaEditFieldLabel = uilabel(app.Tab);
            app.LamdaEditFieldLabel.Text = 'Lamda';
            app.LamdaEditField = uieditfield(app.Tab, 'numeric');
            app.RegionEditFieldLabel = uilabel(app.Tab);
            app.RegionEditFieldLabel.Text = 'Region';
            app.RegionEditField = uieditfield(app.Tab, 'numeric');
            app.PR = uibutton(app.Tab, 'push');
            app.PR.ButtonPushedFcn = createCallbackFcn(app, @previewNext, true);
            app.PR.Visible = 'off';
            app.PR.Text = '>';
            app.PL = uibutton(app.Tab, 'push');
            app.PL.ButtonPushedFcn = createCallbackFcn(app, @previewBack, true);
            app.PL.Visible = 'off';
            app.PL.Text = '<';
            app.ApplyButton = uibutton(app.Tab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.threshAx = uiaxes(app.Tab2);
            title(app.threshAx, 'Title')
            xlabel(app.threshAx, 'X')
            ylabel(app.threshAx, 'Y')
            zlabel(app.threshAx, 'Z')
            colormap(app.threshAx, 'gray')
            app.ThresholdEditFieldLabel = uilabel(app.Tab2);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Tab2, 'numeric');
            app.ThresholdEditField.Editable = 'off';
            app.CalcButton = uibutton(app.Tab2, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Calc';
            app.phaseNext = uibutton(app.Tab2, 'push');
            app.phaseNext.Text = '>';
            app.phasePrev = uibutton(app.Tab2, 'push');
            app.phasePrev.Text = '<';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gooApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end